classdef eng6final_v5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CloseButton                  matlab.ui.control.Button
        ImportSensorDataButton       matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        SpeedRegressionLineTextAreaLabel  matlab.ui.control.Label
        SpeedRegressionLineTextArea  matlab.ui.control.TextArea
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportSensorDataButton
        function ImportSensorDataButtonPushed(app, event)
            %gets the name of file from user
            [filename,filepath]=uigetfile({'*.mat'},'Select File to Open');
            fullname=[filepath,filename];
            % loads file
            FileData=load(fullname);
            
            
            %converting the data file into a .csv
            writetimetable(FileData.Position,'data.csv')
            
            x = readtable('data.csv');
            app.UITable.ColumnName = x.Properties.VariableNames;
            opencsv = xlsread('data.csv');
            app.UITable.Data = array2table(opencsv);
            
            table = readtable("data.csv");
            
            r = polyfit(table.speed,[1:length(table.speed)],1);
            
            str = ['y = ', num2str(r(1)), 'x + ', num2str(r(2))];
            app.SpeedRegressionLineTextArea.Value = {str};
            
            plot(app.UIAxes,table.speed)
            plot(app.UIAxes2,table.latitude)
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ImportSensorDataButton = uibutton(app.UIFigure, 'push');
            app.ImportSensorDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSensorDataButtonPushed, true);
            app.ImportSensorDataButton.Text = 'Import Sensor Data';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SpeedRegressionLineTextAreaLabel = uilabel(app.UIFigure);
            app.SpeedRegressionLineTextAreaLabel.Text = 'Speed Regression Line';
            app.SpeedRegressionLineTextArea = uitextarea(app.UIFigure);
            app.SpeedRegressionLineTextArea.Editable = 'off';
            app.SpeedRegressionLineTextArea.Value = {'y=Ax+B'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Speed')
            xlabel(app.UIAxes, 'Time (in seconds)')
            ylabel(app.UIAxes, 'Speed (in m/s)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Latitude')
            xlabel(app.UIAxes2, 'Time (in seconds)')
            ylabel(app.UIAxes2, 'Latitude (in degrees')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eng6final_v5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end